classdef lakemodelapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        ParameterDescriptionTextArea  matlab.ui.control.TextArea
        ParameterDescriptionTextAreaLabel  matlab.ui.control.Label
        sButton                       matlab.ui.control.StateButton
        PEditField                    matlab.ui.control.EditField
        PEditFieldLabel               matlab.ui.control.Label
        layersEditField               matlab.ui.control.EditField
        layersEditFieldLabel          matlab.ui.control.Label
        TEditField                    matlab.ui.control.EditField
        TEditFieldLabel               matlab.ui.control.Label
        dtEditField                   matlab.ui.control.EditField
        dtEditFieldLabel              matlab.ui.control.Label
        IbEditField                   matlab.ui.control.EditField
        IbEditFieldLabel              matlab.ui.control.Label
        AEditField                    matlab.ui.control.EditField
        AEditFieldLabel               matlab.ui.control.Label
        muEditField                   matlab.ui.control.EditField
        muEditFieldLabel              matlab.ui.control.Label
        gaEditField                   matlab.ui.control.EditField
        gaEditFieldLabel              matlab.ui.control.Label
        bEditField                    matlab.ui.control.EditField
        bEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.EditField
        aEditFieldLabel               matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: bEditField, sButton
        function sButtonValueChanged(app, event)
           value = app.sButton.Value;
% 读取输入参数
Ib = str2num(app.IbEditField.Value);
A = str2num(app.AEditField.Value);
mu = str2num(app.muEditField.Value);
dt = str2num(app.dtEditField.Value);
ga = str2num(app.gaEditField.Value);
a = str2num(app.aEditField.Value);
T = str2num(app.TEditField.Value);
P = str2num(app.PEditField.Value);
b = str2num(app.bEditField.Value);
layers = str2num(app.layersEditField.Value);

P = str2num(app.PEditField.Value);
ga = str2num(app.gaEditField.Value);
mu = str2num(app.muEditField.Value);
A = str2num(app.AEditField.Value);

if length(P) ~= layers || length(ga) ~= layers || length(mu) ~= layers || length(A) ~= layers
    uialert(app.UIFigure, 'The input quantities of P, GA, MU, and A must be the same as those of layers！', 'Typing error');
    return;
end

[t, P_result, A_result] = lakeModel(a, b, ga, mu, A, Ib, dt, T, layers, P);

% Plot phosphorus concentrations
cla(app.UIAxes); % Clear the axes
hold(app.UIAxes, 'on');
colors = lines(layers); % Automatic color matching
for j = 1:layers
    plot(app.UIAxes, t, P_result(j,:), 'Color', colors(j,:), 'LineWidth', 2);
end
hold(app.UIAxes, 'off');
legend(app.UIAxes, compose('Layer %d', 1:layers));
xlabel(app.UIAxes, 'Time');
ylabel(app.UIAxes, 'Phosphorus concentration');

% Plot algae concentrations
cla(app.UIAxes_2);
hold(app.UIAxes_2, 'on');
for j = 1:layers
    plot(app.UIAxes_2, t, A_result(j,:), 'Color', colors(j,:), 'LineWidth', 2);
end
hold(app.UIAxes_2, 'off');
legend(app.UIAxes_2, compose('Layer %d', 1:layers));
xlabel(app.UIAxes_2, 'Time');
ylabel(app.UIAxes_2, 'Algal concentration');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {812, 812};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {524, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'text');
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @sButtonValueChanged, true);
            app.gaEditFieldLabel = uilabel(app.LeftPanel);
            app.gaEditFieldLabel.Text = 'ga';
            app.gaEditField = uieditfield(app.LeftPanel, 'text');
            app.muEditFieldLabel = uilabel(app.LeftPanel);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.LeftPanel, 'text');
            app.AEditFieldLabel = uilabel(app.LeftPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.LeftPanel, 'text');
            app.IbEditFieldLabel = uilabel(app.LeftPanel);
            app.IbEditFieldLabel.Text = 'Ib';
            app.IbEditField = uieditfield(app.LeftPanel, 'text');
            app.dtEditFieldLabel = uilabel(app.LeftPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.LeftPanel, 'text');
            app.TEditFieldLabel = uilabel(app.LeftPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.LeftPanel, 'text');
            app.layersEditFieldLabel = uilabel(app.LeftPanel);
            app.layersEditFieldLabel.Text = 'layers';
            app.layersEditField = uieditfield(app.LeftPanel, 'text');
            app.PEditFieldLabel = uilabel(app.LeftPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LeftPanel, 'text');
            app.sButton = uibutton(app.LeftPanel, 'state');
            app.sButton.ValueChangedFcn = createCallbackFcn(app, @sButtonValueChanged, true);
            app.sButton.Text = 's';
            app.ParameterDescriptionTextAreaLabel = uilabel(app.LeftPanel);
            app.ParameterDescriptionTextAreaLabel.Text = 'Parameter Description';
            app.ParameterDescriptionTextArea = uitextarea(app.LeftPanel);
            app.ParameterDescriptionTextArea.Value = {'P	Phosphorus concentration	                                                                           (mg/L)'; 'a	Coefficient affecting the change of phosphorus concentration (P)	                   (mg/L/day)'; 'b	Natural decay coefficient of phosphorus concentration	                                   (day−1)'; 'ga	Coefficient affecting algal growth	                                                                   (mg/L/day)'; 'mu	The death or decay rate of algae	                                                                   (day−1)'; 'A	Initial algal concentration	                                                                           (mg/L)'; 'Ib	Basic phosphorus input rate	                                                                           (mg/L/day)'; 'dt	Time step	                                                                                                   (days)'; 'T	Total simulation time	                                                                                   (days)'; 'layers	Number of layers of water body	                                                           (layers)'};
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Phosphorus concentration and algae concentration prediction app considering stratification conditions'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '(a) Phosphorus concentration')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Phosphorus concentration (mg/L)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '(b) Algal concentration')
            xlabel(app.UIAxes_2, 'Time (days)')
            ylabel(app.UIAxes_2, 'Algal concentration (mg/L)')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lakemodelapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end